classdef fibreFinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProcessbatchButton            matlab.ui.control.Button
        DisplayPanel                  matlab.ui.container.Panel
        AddscalebarCheckBox           matlab.ui.control.CheckBox
        VisualisationDropDown         matlab.ui.control.DropDown
        VisualisationDropDownLabel    matlab.ui.control.Label
        ProcessingparametersPanel     matlab.ui.container.Panel
        WatershedthresholdSlider      matlab.ui.control.Slider
        WatershedthresholdLabel       matlab.ui.control.Label
        SplitbranchesCheckBox         matlab.ui.control.CheckBox
        InvertheightmapCheckBox       matlab.ui.control.CheckBox
        FlatteningscaleSlider         matlab.ui.control.Slider
        FlatteningscaleSliderLabel    matlab.ui.control.Label
        MaximumridgescaleSlider       matlab.ui.control.Slider
        MaximumridgescaleSliderLabel  matlab.ui.control.Label
        MinimumridgescaleSlider       matlab.ui.control.Slider
        MinimumridgescaleLabel        matlab.ui.control.Label
        DataselectionPanel            matlab.ui.container.Panel
        AvailablefilesListBox         matlab.ui.control.ListBox
        AvailablefilesLabel           matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        DirectoryEditField            matlab.ui.control.EditField
        DirectoryEditFieldLabel       matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %File-associated settings
        rootDir % Root directory
        currFile % Currently selected filename

        %Processing associated settings
        processSets % Settings used to apply network analysis algorithms
        dx % Spatial scale (pixel size) of currently selected image
        invert % Whether or not to invert the current image

        %GUI settings
        renderType % Currently selected rendering choice. Numerical - 'Raw' = 1, 'Flattened' = 2, 'Ridges' = 3, 'Network' = 4, 'Fibres' = 5
        deepestRender % The current deepest level of the analysis of the current image that is stored in memory. Numerical, matching renderType
        scaleBar % Whether to add a scalebar to the current render

        %Image data
        currFrame % Currently selected image (raw data)
        currFlat %Current flattened version of image
        currRidge %Currently detected ridge image
        currRidgeSeg %Currently segmented/skeletonized version of ridges - goes on to form basis of network
        currWidth %Currently measured local ridge width values
        currNsc %Currently detected ridge intensity scores

        %Network data
        rawNodes
        rawLinks % Network (graph representation) of current dataset
        noteNodes
        noteLinks % Annotated version of the network (with fibre IDs assigned)
        fibreProps
    end
    
    methods (Access = private)
        %TESTFILES goes through the contents of the currently selected
        %directory and sees which files are in a readable format.
        function readableFiles = testFiles(app)
            candidates = dir(app.rootDir);
            readableFiles = {};

            %Following file extensions are permissible: .txt, .tif
            for i = 1:size(candidates,1)
                if numel(candidates(i).name) > 4
                    if sum(strcmp(candidates(i).name(end-3:end),{'.txt'})) > 0
                        readableFiles = [readableFiles;candidates(i).name];
                    end
                end
            end
        end

        %LOADIMG loads the currently selected image, along with pixel size
        %metadata.
        function [] = loadImg(app)
            standardScale = 0.2; %Standard pixel size that all images will be rescaled to
            fileExt = app.currFile(end-3:end);
            if strcmp(fileExt,'.txt')
                [AFMmat,dx] = txtToMat(fullfile(app.rootDir,app.currFile));
                origZ = AFMmat(:,:,3) - min(min(AFMmat(:,:,3))); %Sets the smallest value to zero
                rescaleFac = dx/standardScale;
                app.currFrame = imresize(origZ,rescaleFac);
                app.dx = standardScale;
            elseif strcmp(fileExt,'.tif')
                tifMat = imread(fullfile(app.rootDir,app.currFile));
                info = imfinfo(fullfile(app.rootDir,app.currFile));
                %Need to figure out how to get pixel size from
                %metadata, set to 1 for now
                app.dx = 1;
                app.currFrame = tifMat - min(min(tifMat));
            end
            
            if app.invert
                app.currFrame = max(app.currFrame(:))-app.currFrame;
            end
        end

        %RENDERVIEW renders the currently selected viewpoint on the
        %selected image.
        function [] = renderView(app)
            cla(app.UIAxes)
            switch app.renderType
                case 1 %'Raw'
                    imagesc(app.UIAxes,app.currFrame)
                    colormap(app.UIAxes,'turbo')
                    caxis(app.UIAxes,[prctile(app.currFrame(:),1),prctile(app.currFrame(:),99)])
                case 2 %'Flattened'
                    imagesc(app.UIAxes,app.currFlat)
                    colormap(app.UIAxes,'turbo')
                    caxis(app.UIAxes,[prctile(app.currFlat(:),1),prctile(app.currFlat(:),99)])
                case 3 %'Ridges'
                    imgLow = prctile(app.currFlat(:),1);
                    imgHi = prctile(app.currFlat(:),99);
                    sharpImg = (app.currFlat-imgLow)/(imgHi-imgLow);

                    fibImg = cat(3,sharpImg,sharpImg-app.currRidge,sharpImg-app.currRidge);
                    imshow(fibImg,'Parent',app.UIAxes)
                case 4 %'Network'
                    visualiseAnnotatedNetworkGUI(app.rawLinks,app.rawNodes,app.currFlat,app.UIAxes)
                case 5 %'Fibres'
                    visualiseAnnotatedFibresGUI(app.fibreProps.(app.currFile(1:end-4)),app.noteNodes,app.noteLinks,app.currFlat,'Length',app.dx,app.UIAxes)
            end

            axis(app.UIAxes,'tight')
            axis(app.UIAxes,'equal')
            
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YDir = 'reverse';

            if app.scaleBar
                displayScaleBar(app.UIAxes,app.dx,app.currFrame)
            end
        end
    
        %REFRESHANALYSIS updates the current set of analysed data to match a
        %newly selected image file and/or visualisation type
        function [] = refreshAnalysis(app)
            if app.deepestRender < 1
                app.loadImg();
            end
            if app.deepestRender < 2 && app.renderType > 1
                app.calcFlattened();
            end
            if app.deepestRender < 3 && app.renderType > 2
                app.calcRidges();
            end
            if app.deepestRender < 4 && app.renderType > 3
                app.calcNetwork();
            end
            if app.deepestRender < 5 && app.renderType > 4
                app.calcFibres();
            end
            app.deepestRender = app.renderType;
        end

        %CALCFLATTENED calculates the flattened version of the input image
        %based on the currently selected flattening parameters
        function [] = calcFlattened(app)
            tmpFlat = app.currFrame - imgaussfilt(app.currFrame,app.processSets.flattenScale/app.dx);
            app.currFlat = tmpFlat - min(tmpFlat(:));
        end
        
        %CALCRIDGES calls up the ridge-detection framework using the
        %currently selected ridge values
        function [] = calcRidges(app)
            ridgeScale = linspace(app.processSets.minRidgeScale/app.dx,app.processSets.maxRidgeScale/app.dx,20); %Scales are in pixels
            [ridgeImg,app.currWidth,app.currNsc,app.currRidge] = bwRidgeCenterMod(app.currFlat,ridgeScale,app.processSets.waterThresh,app.dx,5);
            app.currRidgeSeg = bwskel(logical(ridgeImg),'MinBranchLength',round(2/app.dx));
        end

        %CALCNETWORK calls up the graph reconstruction and cleaning
        %framework using the currently detected ridges in the current image
        function [] = calcNetwork(app)
            [app.rawNodes,app.rawLinks] = makeAndCleanNetwork(app.currRidgeSeg,app.currFrame,app.currWidth,app.currNsc);
        end
        
        %CALCFIBRES annotates the fibres in the current network and
        %measures the fibre properties.
        function [] = calcFibres(app)
            %Find long fibres directly from network structure
            [app.noteNodes,app.noteLinks] = annotateNetwork(app.rawNodes,app.rawLinks,app.currRidge,app.dx);
            %Measure properties of long fibres
            app.fibreProps.(app.currFile(1:end-4)) = measureFibres(app.noteNodes,app.noteLinks,app.currFrame,app.dx);
        end

        %BATCHPROCESS applies the full processing pipeline to all the
        %datasets in the current root directory that can be analysed.
        function [] = batchProcess(app)
            for fileName = app.AvailablefilesListBox.Items
                app.currFile = fileName;
                app.loadImg();
                app.calcFlattened();
                app.calcRidges();
                app.calcNetwork();
                app.calcFibres();
            end

            fibreProps = app.fibreProps;
            processSets = app.processSets;
            save(fullfile(app.rootDir,'foundFibres.mat'),'fibreProps','processSets')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.renderType = find(strcmp(app.VisualisationDropDown.Value,app.VisualisationDropDown.Items));
            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.processSets.maxRidgeScale = app.MaximumridgescaleSlider.Value;
            app.processSets.waterThresh = app.WatershedthresholdSlider.Value;
            app.deepestRender = 0;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.rootDir = uigetdir();
            app.DirectoryEditField.Value = app.rootDir;
            goodFiles = app.testFiles();
            app.AvailablefilesListBox.Items = goodFiles;
            if ~isempty(goodFiles)
                app.AvailablefilesListBox.Value = goodFiles{1};
                app.currFile = goodFiles{1};
                app.deepestRender = 0;
                app.refreshAnalysis();
                app.renderView();
            end
        end

        % Value changed function: VisualisationDropDown
        function VisualisationDropDownValueChanged(app, event)
            app.renderType = find(strcmp(app.VisualisationDropDown.Value,app.VisualisationDropDown.Items));
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: AvailablefilesListBox
        function AvailablefilesListBoxValueChanged(app, event)
            app.currFile = app.AvailablefilesListBox.Value;
            app.deepestRender = 0;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            app.rootDir = app.DirectoryEditField.Value;
            if ~exist(app.rootDir,'dir')
                errordlg('No such directory exists...','Missing directory')
                app.rootDir = '';
                app.DirectoryEditField.Value = '';
            else
                goodFiles = app.testFiles();
                app.AvailablefilesListBox.Items = goodFiles;
                if ~isempty(goodFiles)
                    app.AvailablefilesListBox.Value = goodFiles{1};
                    app.currFile = goodFiles{1};
                    app.refreshAnalysis();
                    app.renderView();
                end
            end
        end

        % Value changed function: FlatteningscaleSlider
        function FlatteningscaleSliderValueChanged(app, event)
            app.processSets.flattenScale = app.FlatteningscaleSlider.Value;
            app.deepestRender = 1;
            app.refreshAnalysis();
            app.renderView();            
        end

        % Value changed function: MinimumridgescaleSlider
        function MinimumridgescaleSliderValueChanged(app, event)
            app.processSets.minRidgeScale = app.MinimumridgescaleSlider.Value;
            app.MaximumridgescaleSlider.Limits(1) = max(app.processSets.minRidgeScale+2,5);
            app.deepestRender = 2;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: MaximumridgescaleSlider
        function MaximumridgescaleSliderValueChanged(app, event)
            app.processSets.maxRidgeScale = app.MaximumridgescaleSlider.Value;
            app.MinimumridgescaleSlider.Limits(2) = max(app.processSets.maxRidgeScale-2,2);
            app.deepestRender = 2;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: WatershedthresholdSlider
        function WatershedthresholdSliderValueChanged(app, event)
            app.processSets.waterThresh = app.WatershedthresholdSlider.Value;
            app.deepestRender = 3;
            app.refreshAnalysis();
            app.renderView();
        end

        % Value changed function: InvertheightmapCheckBox
        function InvertheightmapCheckBoxValueChanged(app, event)
            app.invert = app.InvertheightmapCheckBox.Value;
            app.deepestRender = 0;
            app.refreshAnalysis();
            app.renderView();
        end

        % Button pushed function: ProcessbatchButton
        function ProcessbatchButtonPushed(app, event)
            app.batchProcess();
        end

        % Value changed function: AddscalebarCheckBox
        function AddscalebarCheckBoxValueChanged(app, event)
            app.scaleBar = app.AddscalebarCheckBox.Value;
            app.renderView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.DataselectionPanel = uipanel(app.UIFigure);
            app.DataselectionPanel.Title = 'Data selection';
            app.DirectoryEditFieldLabel = uilabel(app.DataselectionPanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.DataselectionPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.DataselectionPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.AvailablefilesLabel = uilabel(app.DataselectionPanel);
            app.AvailablefilesLabel.Text = {'Available'; 'files'};
            app.AvailablefilesListBox = uilistbox(app.DataselectionPanel);
            app.AvailablefilesListBox.Items = {};
            app.AvailablefilesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailablefilesListBoxValueChanged, true);
            app.AvailablefilesListBox.Value = {};
            app.ProcessingparametersPanel = uipanel(app.UIFigure);
            app.ProcessingparametersPanel.Title = 'Processing parameters';
            app.MinimumridgescaleLabel = uilabel(app.ProcessingparametersPanel);
            app.MinimumridgescaleLabel.Text = {'Minimum'; 'ridge scale'};
            app.MinimumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MinimumridgescaleSlider.Limits = [2.4 10];
            app.MinimumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MinimumridgescaleSliderValueChanged, true);
            app.MinimumridgescaleSlider.Value = 3;
            app.MaximumridgescaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.MaximumridgescaleSliderLabel.Text = {'Maximum'; 'ridge scale'};
            app.MaximumridgescaleSlider = uislider(app.ProcessingparametersPanel);
            app.MaximumridgescaleSlider.Limits = [5 20];
            app.MaximumridgescaleSlider.ValueChangedFcn = createCallbackFcn(app, @MaximumridgescaleSliderValueChanged, true);
            app.MaximumridgescaleSlider.Value = 10;
            app.FlatteningscaleSliderLabel = uilabel(app.ProcessingparametersPanel);
            app.FlatteningscaleSliderLabel.Text = {'Flattening'; 'scale'};
            app.FlatteningscaleSlider = uislider(app.ProcessingparametersPanel);
            app.FlatteningscaleSlider.Limits = [0.4 10];
            app.FlatteningscaleSlider.ValueChangedFcn = createCallbackFcn(app, @FlatteningscaleSliderValueChanged, true);
            app.FlatteningscaleSlider.Value = 1;
            app.InvertheightmapCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.InvertheightmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertheightmapCheckBoxValueChanged, true);
            app.InvertheightmapCheckBox.Text = 'Invert heightmap';
            app.SplitbranchesCheckBox = uicheckbox(app.ProcessingparametersPanel);
            app.SplitbranchesCheckBox.Text = 'Split branches';
            app.WatershedthresholdLabel = uilabel(app.ProcessingparametersPanel);
            app.WatershedthresholdLabel.Text = {'Watershed '; 'threshold'};
            app.WatershedthresholdSlider = uislider(app.ProcessingparametersPanel);
            app.WatershedthresholdSlider.Limits = [1 8];
            app.WatershedthresholdSlider.MajorTicks = [1 2 3 4 5 6 7 8];
            app.WatershedthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @WatershedthresholdSliderValueChanged, true);
            app.WatershedthresholdSlider.Value = 3;
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.VisualisationDropDownLabel = uilabel(app.DisplayPanel);
            app.VisualisationDropDownLabel.Text = 'Visualisation';
            app.VisualisationDropDown = uidropdown(app.DisplayPanel);
            app.VisualisationDropDown.Items = {'Raw', 'Flattened', 'Ridges', 'Network', 'Fibres'};
            app.VisualisationDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualisationDropDownValueChanged, true);
            app.VisualisationDropDown.Value = 'Raw';
            app.AddscalebarCheckBox = uicheckbox(app.DisplayPanel);
            app.AddscalebarCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddscalebarCheckBoxValueChanged, true);
            app.AddscalebarCheckBox.Text = 'Add scalebar';
            app.ProcessbatchButton = uibutton(app.UIFigure, 'push');
            app.ProcessbatchButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessbatchButtonPushed, true);
            app.ProcessbatchButton.Text = 'Process batch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fibreFinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end