classdef fibrePlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ExportoptionsMenu          matlab.ui.container.Menu
        ExportdataMenu             matlab.ui.container.Menu
        ExportfigureMenu           matlab.ui.container.Menu
        Tree                       matlab.ui.container.CheckBoxTree
        SelectdirectoryNode        matlab.ui.container.TreeNode
        ColormapDropDown           matlab.ui.control.DropDown
        ColormapDropDownLabel      matlab.ui.control.Label
        DataDropDown               matlab.ui.control.DropDown
        DataDropDownLabel          matlab.ui.control.Label
        PlottypeDropDown           matlab.ui.control.DropDown
        PlottypeDropDownLabel      matlab.ui.control.Label
        ChooserootdirectoryButton  matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Tree-related stuff
        Root % Root directory of current analysis
        dirNames % Names of directories in current root with foundFibre files
        fileNames % Names of image files associated with each foundFibres.mat file in each directory

        dirChecks % Checkboxes of directories in tree currently active
        fileChecks % Checkboxes of files in tree currently active

        dirHands % Handles to checkboxes of directories
        fileHands % Handles to checkboxes of files

        treeMode % 'Whole' at the beginning, when all files/directories should be shown, 'Dir' when only directories should be shown, 'Files' when only files of currDir should be shown, 'Pshape' when one directory is checked.

        % Currently-selected options
        plotType
        dataChosen
        colorMap

        % Export data
        exportDat
        
        % GUI components not covered by App designer
        polarAx
    end

    methods (Access = private)
        function [] = updateDataTypes(app)
            %Updates the 'data' dropdown menu according to the plot type
            %chosen
            switch app.PlottypeDropDown.Value
                case 'Rose plot'
                    app.DataDropDown.Items={'Orientation'};
                    app.DataDropDown.Value='Orientation';
                    app.DataDropDown.Editable='off';
                case 'Histogram'
                    app.DataDropDown.Items={'Length','Width','Orientation'};
                    app.DataDropDown.Value='Length';
                    app.DataDropDown.Editable='on';
                case 'Boxplot'
                    app.DataDropDown.Items={'Length','Width','Orientation','Angular concentration'};
                    app.DataDropDown.Value='Length';
                    app.DataDropDown.Editable='on';
            end
        end

        function [] = updateFileSelection(app)
            %Updates the file selection window when a new root directory is
            %chosen

            % Find directories that fulfil the requirements of the directory structure
            [app.dirNames,app.fileNames] = app.findValidData();

            %Initialise new (empty) check file structures
            app.dirChecks = zeros(size(app.dirNames));
            for i = 1:size(app.dirNames,2)
                app.fileChecks{i} = zeros(size(app.fileNames{i}));
            end

            app.treeMode = 'Whole';
            app.updateTree()
        end

        function assignTreeState(app)
            % Determines the current state of the tree (Whole, directories
            % only or files only) based on currently selected checkboxes
            % and the currently selected plot type
            switch app.plotType
                case 'Boxplot'
                    app.treeMode = 'Dir';
                case {'Histogram','Rose plot'}
                    if sum(app.dirChecks) > 1
                        app.treeMode = 'Dir';
                    elseif sum(cellfun(@(x)numel(x),app.fileChecks) == cellfun(@(x)sum(x),app.fileChecks)) == 0 && sum(cellfun(@(x)sum(x),app.fileChecks)) > 0 %This checks whether less than the maximal number of checkboxes for a given directory are currently ticked
                        app.treeMode = 'Files';
                    elseif sum(cellfun(@(x)numel(x),app.fileChecks) == cellfun(@(x)sum(x),app.fileChecks)) == 1
                        app.treeMode = 'Pshape';
                    else %Should only be available if nothing is ticked
                        app.treeMode = 'Whole';
                    end
            end
        end

        function updateTree(app)
            %Updates the displayed tree

            % Delete pre-existing tree
            collapse(app.Tree,'all')
            app.SelectdirectoryNode.Children.delete;

            switch app.treeMode
                case 'Whole'
                    % Insert into tree structure
                    if isempty(app.dirNames) || isempty(app.fileNames)
                        app.SelectdirectoryNode.Text = 'Please choose valid root directory';
                    else
                        app.SelectdirectoryNode.Text = app.Root;
                        app.dirHands = app.setChildrenNodes(app.SelectdirectoryNode,app.dirNames);
                        for i = 1:size(app.dirNames,2)
                            app.fileHands{i} = app.setChildrenNodes(app.dirHands{i},app.fileNames{i});
                        end
                    end

                    % Re-check any boxes that should be ticked
                    ndList = [];
                    for i = 1:size(app.dirNames,2)
                        if app.dirChecks(i) == 1
                            ndList = [ndList,app.dirHands{i}];
                        end
                        for j = 1:size(app.fileNames{i},2)
                            if app.fileChecks{i}(j) == 1
                                ndList = [ndList,app.fileHands{i}{j}];
                            end
                        end
                    end
                    app.Tree.CheckedNodes = ndList;

                    % Expand tree
                    expand(app.SelectdirectoryNode)
                case 'Dir'
                    app.SelectdirectoryNode.Text = app.Root;
                    app.dirHands = app.setChildrenNodes(app.SelectdirectoryNode,app.dirNames);

                    % Re-check any boxes that should be ticked
                    ndList = [];
                    for i = 1:size(app.dirNames,2)
                        if app.dirChecks(i) == 1
                            ndList = [ndList,app.dirHands{i}];
                        end
                    end
                    app.Tree.CheckedNodes = ndList;
                case 'Pshape'
                    currDirInd = find(cellfun(@(x)sum(x),app.fileChecks) > 0);

                    app.SelectdirectoryNode.Text = app.Root;
                    app.dirHands = app.setChildrenNodes(app.SelectdirectoryNode,app.dirNames);
                    app.fileHands{currDirInd} = app.setChildrenNodes(app.dirHands{currDirInd},app.fileNames{currDirInd});

                    % Re-check any boxes that should be ticked
                    ndList = app.dirHands{currDirInd};
                    for i = 1:size(app.fileNames{currDirInd},2)
                        if app.fileChecks{currDirInd}(i) == 1
                            ndList = [ndList;app.fileHands{currDirInd}{i}];
                        end
                    end
                    app.Tree.CheckedNodes = ndList;
                case 'Files'
                    currDirInd = find(cellfun(@(x)sum(x),app.fileChecks) > 0);

                    app.SelectdirectoryNode.Text = app.Root;
                    app.dirHands = app.setChildrenNodes(app.SelectdirectoryNode,app.dirNames(currDirInd));
                    app.fileHands{currDirInd} = app.setChildrenNodes(app.dirHands{1},app.fileNames{currDirInd});

                    % Re-check any boxes that should be ticked
                    ndList = [];
                    for i = 1:size(app.fileNames{currDirInd},2)
                        if app.fileChecks{currDirInd}(i) == 1
                            ndList = [ndList;app.fileHands{currDirInd}{i}];
                        end
                    end
                    app.Tree.CheckedNodes = ndList;

                    expand(app.SelectdirectoryNode)
                    expand(app.dirHands{1})
            end
        end

        function nodeHands = setChildrenNodes(~,parentNode,childConts)
            % Sets the contents of the given parent node to the specified
            % list of text.
            nodeHands = cell(size(childConts));
            for i = 1:size(childConts,2)
                nodeHands{i} = uitreenode(parentNode,'Text',childConts{i});
            end
        end

        function [dirNames,fileNames] = findValidData(app)
            % Finds all directories and fibreProp-associated files that
            % fulfil structure requirements
            dirConts = dir(app.Root);
            dirFolds = vertcat(dirConts.isdir);
            dirNames = string({dirConts.name});
            badInds = or(dirNames=='.',dirNames=='..');
            dirNames(or(badInds',~dirFolds)) = []; %Should just be child directories now

            % Remove sub-directories not containing analysed data
            badInds = [];
            for i = 1:size(dirNames,2)
                if ~exist(fullfile(app.Root,dirNames{i},'foundFibres.mat'),'file')
                    badInds = [badInds;i];
                end
            end

            dirNames(badInds) = [];

            % Find the names of individual analysed images
            fileNames = cell(size(dirNames));
            for i = 1:size(dirNames,2)
                load(fullfile(app.Root,dirNames{i},'foundFibres.mat'),'fibreProps')
                fileNames{i} = fieldnames(fibreProps)';
            end
        end

        function [names, nameFromFolder] = pruneFolderContent(~,folderContent)
            names = string({folderContent.name});
            nameFromFolder = {folderContent.isdir};
            %Delete nodes with . and .. caused by dir command
            idxDots = find(or(names == ".",names == ".."));
            names(idxDots) = [];
            nameFromFolder(idxDots) = [];
        end

        function plotData(app)
            % Plots data in the UI axes, given the currently selected files
            % and GUI settings.
            switch app.plotType
                case 'Rose plot'
                    app.polarAx.Visible = 'on';
                    cla(app.polarAx)
                    app.UIAxes.Visible = 'off';

                    insertDat = app.extractRoseplotData();
                    plotLocalOrientationHistogram(app.polarAx,insertDat,app.colorMap)
                    
                    app.exportDat = insertDat;
                case 'Histogram'
                case 'Boxplot'
            end
        end

        function plottingData = extractRoseplotData(app)
            %Pulls out orientation data for roseplots

            %Two versions of the output: one based on if data should be
            %pooled across samples, the other based on if individual
            %samples should be shown separately
            if sum(app.dirChecks) > 1 %Pooled option active
                %Check whether a cell orientation file exists for all
                %datasets selected
                for i = 1:size(app.dirNames,2)
                    if app.dirChecks(i) == 1
                        if ~exist(fullfile(app.Root,app.dirNames(i),'CellOrientations.csv'),'file') == 2
                            errordlg('CellOrientations.csv file not available for all datasets. Aborting...')
                            plottingData = {};
                            return
                        end
                    end
                end

                plottingData = cell(sum(app.dirChecks),1);
                for i = 1:size(app.dirNames,2)
                    if app.dirChecks(i) == 1
                        cellOrients = readmatrix(fullfile(app.Root,app.dirNames{i},'CellOrientations.csv'),delimitedTextImportOptions);
                        load(fullfile(app.Root,app.dirNames{i},'foundFibres.mat'),'fibreProps')
                        orientList = [];
                        for j = 1:size(app.fileNames{i},2)
                            orientLoc = find(strcmp([app.fileNames{i}{j},'.txt'],cellOrients(:,1)));
                            if numel(orientLoc) == 1
                                orientSubList = vertcat(fibreProps.(app.fileNames{i}{j}).localOrientation) - deg2rad(str2double(cellOrients{orientLoc,2}));
                                orientList = [orientList;orientSubList];
                            else
                                errordlg('CellOrientations.csv is incorrectly formatted. Offending directory: ',fullfile(app.Root,app.dirNames{i}),'. Aborting...')
                                plottingData = {};
                                return
                            end
                        end
                        plottingData{i} = orientList;
                    end
                end
            else %Individual samples

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Root = 'Choose directory...';
            app.plotType = app.PlottypeDropDown.Value;
            app.colorMap = brewermap([],app.ColormapDropDown.Value);
            app.dataChosen = app.DataDropDown.Value;

            %Initialise tree storage with empty structures
            app.dirNames = {};
            app.fileNames = {{}};
            app.dirChecks = [];
            app.fileChecks = {[]};

            app.updateDataTypes();
            app.updateFileSelection();

            %Create polar axes that occupy the same space as the UI axes.
            %These will be made visible if the rose plot option is chosen
            app.polarAx = polaraxes(app.UIFigure,'Position',app.UIAxes.Position);
            app.polarAx.Visible = 'off';
            hold(app.polarAx,'on');
        end

        % Button pushed function: ChooserootdirectoryButton
        function ChooserootdirectoryButtonPushed(app, event)
            tryDir = uigetdir();
            if tryDir ~= 0 %If the user didn't choose 'cancel' or 'x'
                app.Root = tryDir;
                app.updateFileSelection();

                app.plotData();
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % Updates the internal checkbox representation when
            % files/directories are selected/unselected, then refreshes the tree
            checkedNodes = app.Tree.CheckedNodes;

            checkedNames = cell(size(checkedNodes));
            for i = 1:size(checkedNodes,1)
                checkedNames{i} = checkedNodes(i).Text;
            end

            dirsChecked = zeros(size(app.dirNames));
            filesChecked = cell(size(app.fileNames));
            for i = 1:size(app.dirNames,2)
                dirsChecked(i) = sum(strcmp(app.dirNames{i},checkedNames));
                filesChecked{i} = ones(size(app.fileNames{i}))*sum(strcmp(app.dirNames{i},checkedNames)); %If the directory box is ticked, all its children must be too (this may be implicit, if the tree is in the 'Dir' state).

                if sum(strcmp(app.dirNames{i},checkedNames)) == 0 % Individual children may be ticked, even if the parent directory is not
                    for j = 1:size(app.fileNames{i},2)
                        filesChecked{i}(j) = sum(strcmp(app.fileNames{i}{j},checkedNames));
                    end
                end
            end

            app.dirChecks = dirsChecked;
            app.fileChecks = filesChecked;

            preState = app.treeMode;
            app.assignTreeState();
            if ~strcmp(preState,app.treeMode)
                app.updateTree();
            end
            app.plotData();
        end

        % Value changed function: PlottypeDropDown
        function PlottypeDropDownValueChanged(app, event)
            app.plotType = app.PlottypeDropDown.Value;

            app.assignTreeState();
            app.updateTree();
            app.plotData();
        end

        % Value changed function: DataDropDown
        function DataDropDownValueChanged(app, event)
            app.dataChosen = app.DataDropDown.Value;

            app.plotData();
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.colorMap = brewermap([],app.ColormapDropDown.Value);

            app.plotData();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExportoptionsMenu = uimenu(app.UIFigure);
            app.ExportoptionsMenu.Text = 'Export options';
            app.ExportdataMenu = uimenu(app.ExportoptionsMenu);
            app.ExportdataMenu.Text = 'Export data';
            app.ExportfigureMenu = uimenu(app.ExportoptionsMenu);
            app.ExportfigureMenu.Text = 'Export figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChooserootdirectoryButton = uibutton(app.UIFigure, 'push');
            app.ChooserootdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooserootdirectoryButtonPushed, true);
            app.ChooserootdirectoryButton.Text = 'Choose root directory';
            app.PlottypeDropDownLabel = uilabel(app.UIFigure);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.UIFigure);
            app.PlottypeDropDown.Items = {'Rose plot', 'Boxplot', 'Histogram'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeDropDownValueChanged, true);
            app.PlottypeDropDown.Value = 'Rose plot';
            app.DataDropDownLabel = uilabel(app.UIFigure);
            app.DataDropDownLabel.Text = 'Data';
            app.DataDropDown = uidropdown(app.UIFigure);
            app.DataDropDown.Items = {};
            app.DataDropDown.ValueChangedFcn = createCallbackFcn(app, @DataDropDownValueChanged, true);
            app.DataDropDown.Value = {};
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'Accent', 'Dark2', 'Paired', 'Pastel1', 'Pastel2', 'Set1', 'Set2', 'Set3'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Accent';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SelectdirectoryNode = uitreenode(app.Tree);
            app.SelectdirectoryNode.Text = 'Select directory...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fibrePlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end